classdef test_GUI < matlab.ui.componentcontainer.ComponentContainer

    % Properties that correspond to underlying components
    properties (Access = private, Transient, NonCopyable)
        NegativeCheckBox          matlab.ui.control.CheckBox
        BrightnessB               matlab.ui.control.Slider
        BrightnessbSliderLabel    matlab.ui.control.Label
        BrightnessA               matlab.ui.control.Slider
        BrightnessaSliderLabel    matlab.ui.control.Label
        LoadImageButton           matlab.ui.control.Button
        ChooseImageDropDown       matlab.ui.control.DropDown
        ChooseImageDropDownLabel  matlab.ui.control.Label
        OutputImage               matlab.ui.control.UIAxes
        InputImage                matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadImageButton
        function LoadImageButtonPushed(comp, event)
            nama = comp.ChooseImageDropDown.Value;
            I = imread([nama, '.bmp']);
            imshow(I, 'Parent', comp.InputImage);
            imshow(I, 'Parent', comp.OutputImage);
        end

        % Value changed function: BrightnessA
        function Brightness_a_Changed(comp, event)
            a = event.Value;
            b = comp.BrightnessB.Value;

            I = findobj(comp.InputImage, 'Type', 'image');
            I = I.CData;
            [M, N, C] = size(I);

            bright = zeros(M, N, C);
            for i = 1 : M
                for j = 1 : N
                    for k = 1 : C
                        bright(i,j,k) = a * I(i,j,k) + b;
                    
                        if bright(i,j,k) > 255
                            bright(i,j,k) = 255;
                        elseif bright(i,j,k) < 0
                            bright(i,j,k) = 0;
                        end
                    end
                end
            end
            
            bright = uint8(bright);
            
            out_I = findobj(comp.OutputImage, 'Type', 'image');
            out_I.CData = bright;

            if comp.NegativeCheckBox.Value == 1
                NegativeCheckBoxValueChanged(comp, comp.NegativeCheckBox.Value == 1);
            end
        end

        % Value changed function: BrightnessB
        function Brightness_b_Changed(comp, event)
            a = comp.BrightnessA.Value;
            b = event.Value;

            I = findobj(comp.InputImage, 'Type', 'image');
            I = I.CData;
            [M, N, C] = size(I);

            bright = zeros(M, N, C);
            for i = 1 : M
                for j = 1 : N
                    for k = 1 : C
                        bright(i,j,k) = a * I(i,j,k) + b;
                    
                        if bright(i,j,k) > 255
                            bright(i,j,k) = 255;
                        elseif bright(i,j,k) < 0
                            bright(i,j,k) = 0;
                        end
                    end
                end
            end
            
            bright = uint8(bright);
            
            I_out = findobj(comp.OutputImage, 'Type', 'image');
            I_out.CData = bright;

            if comp.NegativeCheckBox.Value == 1
                NegativeCheckBoxValueChanged(comp, comp.NegativeCheckBox.Value == 1);
            end
        end

        % Value changed function: NegativeCheckBox
        function NegativeCheckBoxValueChanged(comp, event)
            I_out = findobj(comp.OutputImage, 'Type', 'image');
            I_out = I_out.CData;
            [M, N, C] = size(I_out);
            
            neg = zeros(M, N, C);
            for i = 1 : M
                for j = 1 : N
                    for k = 1 : C
                        neg(i,j,k) = 255 - I_out(i,j,k);
                    end
                end
            end
            
            neg = uint8(neg);
            I_out = findobj(comp.OutputImage, 'Type', 'image');
            I_out.CData = neg;
        end
    end

    methods (Access = protected)
        
        % Code that executes when the value of a public property is changed
        function update(comp)
            % Use this function to update the underlying components
            
        end

        % Create the underlying components
        function setup(comp)
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            comp.Position = [1 1 581 399];
            comp.BackgroundColor = [0.94 0.94 0.94];
            comp.InputImage = uiaxes(comp);
            title(comp.InputImage, 'Citra Masukan')
            comp.InputImage.XTick = [];
            comp.InputImage.YTick = [];
            comp.OutputImage = uiaxes(comp);
            title(comp.OutputImage, 'Citra Keluaran')
            comp.OutputImage.XTick = [];
            comp.OutputImage.YTick = [];
            comp.ChooseImageDropDownLabel = uilabel(comp);
            comp.ChooseImageDropDownLabel.Text = 'Choose Image';
            comp.ChooseImageDropDown = uidropdown(comp);
            comp.ChooseImageDropDown.Items = {'boat', 'lena', 'girl'};
            comp.ChooseImageDropDown.Value = 'boat';
            comp.LoadImageButton = uibutton(comp, 'push');
            comp.LoadImageButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @LoadImageButtonPushed, true);
            comp.LoadImageButton.Text = 'Load';
            comp.BrightnessaSliderLabel = uilabel(comp);
            comp.BrightnessaSliderLabel.Text = 'Brightness (a)';
            comp.BrightnessA = uislider(comp);
            comp.BrightnessA.Limits = [1 2];
            comp.BrightnessA.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @Brightness_a_Changed, true);
            comp.BrightnessA.Value = 1;
            comp.BrightnessbSliderLabel = uilabel(comp);
            comp.BrightnessbSliderLabel.Text = 'Brightness (b)';
            comp.BrightnessB = uislider(comp);
            comp.BrightnessB.Limits = [-50 50];
            comp.BrightnessB.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @Brightness_b_Changed, true);
            comp.NegativeCheckBox = uicheckbox(comp);
            comp.NegativeCheckBox.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @NegativeCheckBoxValueChanged, true);
            comp.NegativeCheckBox.Text = '  Negative';
        end
    end
end